classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        Label_6              matlab.ui.control.Label
        Label_5              matlab.ui.control.Label
        Button_2             matlab.ui.control.Button
        Button               matlab.ui.control.Button
        Label_4              matlab.ui.control.Label
        Label_3              matlab.ui.control.Label
        Lamp                 matlab.ui.control.Lamp
        Label_2              matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        FrequencyDomainAxes  matlab.ui.control.UIAxes
        TimeDomainAxes       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Recorder     audiorecorder  % 用于录音的对象
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if isempty(app.Recorder) || ~isvalid(app.Recorder)
                app.Recorder = audiorecorder(44100, 16, 1);% 44100Hz, 16位，单声
            end
            app.Lamp.Color='red';
            app.Label_2.Text="正在录音"'
            app.Recorder = audiorecorder(44100, 16, 1);
            record(app.Recorder);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
        app.Lamp.color='green'
        app.Label_2.Text="可以录音"
            % 停止录音按钮回调函数
    if ~isempty(app.Recorder) && isvalid(app.Recorder)
        % 停止录音
        stop(app.Recorder);
        
        % 获取音频数据
        audioData = getaudiodata(app.Recorder);
        
        % 获取采样率
        Fs = app.Recorder.SampleRate; % 采样率
        N = length(audioData); % 数据长度
        t = (0:N-1)/Fs; % 时间向量
        
        % 调用语音增强函数
        enhanced_signal = VoiceEnhance(audioData, Fs);
        
        % FFT参数
        nfft = 2^nextpow2(N); % 定义 nfft 为 2 的幂次方，确保 FFT 效率
        
        % 计算频域数据
        % 原始信号频域
        Y_original = fft(audioData, nfft);
        P2_original = abs(Y_original/N);
        P1_original = P2_original(1:N/2+1);
        P1_original(2:end-1) = 2*P1_original(2:end-1);
        
        % 增强信号频域
        Y_enhanced = fft(enhanced_signal, nfft);
        P2_enhanced = abs(Y_enhanced/length(enhanced_signal));
        P1_enhanced = P2_enhanced(1:length(enhanced_signal)/2+1);
        P1_enhanced(2:end-1) = 2*P1_enhanced(2:end-1);
        
        % 频率向量
        f = Fs*(0:(N/2))/N;
        
        % 绘制频域图
        plot(app.FrequencyDomainAxes, f, P1_original, 'b');
        hold(app.FrequencyDomainAxes, 'on');
        plot(app.FrequencyDomainAxes, f(1:length(P1_enhanced)), P1_enhanced, 'r');
        hold(app.FrequencyDomainAxes, 'off');
        
        % 设置坐标轴标签和标题
        app.FrequencyDomainAxes.XLabel.String = 'Frequency (Hz)';
        app.FrequencyDomainAxes.YLabel.String = 'Magnitude';
        app.FrequencyDomainAxes.Title.String = 'Frequency Domain';
        
        % 添加图例
        legend(app.FrequencyDomainAxes, 'Original', 'Enhanced', 'Location', 'best');
        
        % 绘制时域图
        plot(app.TimeDomainAxes, t, audioData, 'b');
        hold(app.TimeDomainAxes, 'on');
        plot(app.TimeDomainAxes, t(1:length(enhanced_signal)), enhanced_signal, 'r');
        hold(app.TimeDomainAxes, 'off');
        
        % 设置坐标轴标签和标题
        app.TimeDomainAxes.XLabel.String = 'Time (s)';
        app.TimeDomainAxes.YLabel.String = 'Amplitude';
        app.TimeDomainAxes.Title.String = 'Time Domain';
        
        % 添加图例
        legend(app.TimeDomainAxes, 'Original', 'Enhanced', 'Location', 'best');
        
        
    end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {495, 495};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Tag = 'indication';
            app.Label_2.Text = '可以录音';
            app.Lamp = uilamp(app.LeftPanel);
            app.Lamp.Tag = 'isRecording';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '………………';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '人机对话框';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开启';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '停止';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '语音识别结果';
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '……………………';
            app.RightPanel = uipanel(app.GridLayout);
            app.TimeDomainAxes = uiaxes(app.RightPanel);
            zlabel(app.TimeDomainAxes, 'Z')
            app.FrequencyDomainAxes = uiaxes(app.RightPanel);
            zlabel(app.FrequencyDomainAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end